classdef Harbard_fluids < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        File                            matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ContinuousPipeMenu              matlab.ui.container.Menu
        AddSectionMenu                  matlab.ui.container.Menu
        RemoveSectionMenu               matlab.ui.container.Menu
        ModifySectionMenu               matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        TotalPressureDifferenceEditField  matlab.ui.control.EditField
        TotalPressureDifferenceEditFieldLabel  matlab.ui.control.Label
        PipeSectionsEditField           matlab.ui.control.EditField
        PipeSectionsEditFieldLabel      matlab.ui.control.Label
        ContinuousPipeButton            matlab.ui.control.Button
        psiLabel                        matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        metersLabel_2                   matlab.ui.control.Label
        metersLabel                     matlab.ui.control.Label
        DynamicViscosityCpEditField     matlab.ui.control.NumericEditField
        DynamicViscosityCpEditFieldLabel  matlab.ui.control.Label
        Version10Label                  matlab.ui.control.Label
        ManufacturerLossCoefficientKFactorEditField  matlab.ui.control.NumericEditField
        ManufacturerLossCoefficientKFactorEditFieldLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        TankPressureEditField           matlab.ui.control.NumericEditField
        TankPressureEditFieldLabel      matlab.ui.control.Label
        PressureDifferenceTankInletEditField  matlab.ui.control.NumericEditField
        PressureDifferenceTankInletEditFieldLabel  matlab.ui.control.Label
        PipeFittingsLossValvesSensorsLabel  matlab.ui.control.Label
        FittingsLossEditField           matlab.ui.control.NumericEditField
        FittingsLossEditFieldLabel      matlab.ui.control.Label
        KinematicViscosityEditField     matlab.ui.control.NumericEditField
        KinematicViscosityEditFieldLabel  matlab.ui.control.Label
        FluidDensityEditField           matlab.ui.control.NumericEditField
        FluidDensityEditFieldLabel      matlab.ui.control.Label
        PressurelossduetoelevationEditField  matlab.ui.control.NumericEditField
        PressurelossduetoelevationEditFieldLabel  matlab.ui.control.Label
        PressureDropEditField           matlab.ui.control.NumericEditField
        PressureDropEditFieldLabel      matlab.ui.control.Label
        ReynoldsNoEditField             matlab.ui.control.NumericEditField
        ReynoldsNoEditFieldLabel        matlab.ui.control.Label
        RoughnessEditField              matlab.ui.control.NumericEditField
        RoughnessEditFieldLabel         matlab.ui.control.Label
        FrictionFactorEditField         matlab.ui.control.NumericEditField
        FrictionFactorEditFieldLabel    matlab.ui.control.Label
        PressureDropElevationEditField  matlab.ui.control.NumericEditField
        PressureDropElevationEditFieldLabel  matlab.ui.control.Label
        FrictionLossEditField           matlab.ui.control.NumericEditField
        FrictionLossEditFieldLabel      matlab.ui.control.Label
        FeedbackButton                  matlab.ui.control.Button
        PipeMaterialDropDown            matlab.ui.control.DropDown
        PipeMaterialDropDownLabel       matlab.ui.control.Label
        ViscosityDropDown               matlab.ui.control.DropDown
        ViscosityDropDownLabel          matlab.ui.control.Label
        FluidVelocityEditField          matlab.ui.control.NumericEditField
        FluidVelocityEditFieldLabel     matlab.ui.control.Label
        FluidsDataLabel                 matlab.ui.control.Label
        PipeDataLabel                   matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        PipeAreaEditField               matlab.ui.control.NumericEditField
        PipeAreaEditFieldLabel          matlab.ui.control.Label
        ChangeinPipeHeightEditField     matlab.ui.control.NumericEditField
        ChangeinPipeHeightEditFieldLabel  matlab.ui.control.Label
        PipeLengthEditField             matlab.ui.control.NumericEditField
        PipeLengthEditFieldLabel        matlab.ui.control.Label
        HarbardFluidsLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PipeLengthEditField
        function PipeLengthEditFieldValueChanged(app, event)
            PLvalue = app.PipeLengthEditField.Value;
            
        end

        % Value changed function: ChangeinPipeHeightEditField
        function ChangeinPipeHeightEditFieldValueChanged(app, event)
            CPvalue = app.ChangeinPipeHeightEditField.Value;
            
        end

        % Value changed function: PipeAreaEditField
        function PipeAreaEditFieldValueChanged(app, event)
            PAvalue = app.PipeAreaEditField.Value;
            
        end

        % Value changed function: FluidVelocityEditField
        function FluidVelocityEditFieldValueChanged(app, event)
            value = app.FluidVelocityEditField.Value;
            
        end

        % Value changed function: ViscosityDropDown
        function ViscosityDropDownValueChanged(app, event)
            value = app.ViscosityDropDown.Value;
            
        end

        % Button pushed function: FeedbackButton
        function FeedbackButtonPushed(app, event)
            AluminiumRoughness = 0.002;
            SteelRoughness = 0.045;
            CastIron = 0.26;
   
            % Calculate 
            
            %Reynolds number = (Fluid velocity x Internal pipe diameter) / Kinematic viscosity
            fluidVel = app.FluidVelocityEditField.Value;
            %Pipe diameter from area calculation 
            pipeDiameter = sqrt(app.PipeAreaEditField.Value / 3.14159) * 2;
            %Kinematic viscosity = Dynamic Viscosity / Density 
            kinematic_visc = app.DynamicViscosityCpEditField.Value / app.FluidDensityEditField.Value;
            Reynolds_No = (fluidVel * pipeDiameter) / kinematic_visc;
            
            %Roughness //Alternate solution to lookup table
            roughness = 0;
            if app.PipeMaterialDropDown.Value == "Aluminium"
                roughness = AluminiumRoughness;
            elseif app.PipeMaterialDropDown.Value == "Stainless Steel"
                roughness = SteelRoughness;
            end
            app.RoughnessEditField.Value = roughness;
            
            %Friction factor(Moody Equation) Δpmajor_loss = friction coefficient (pipe lengtg / dh) (ρf v2 / 2)
            friction_factor = 0;
            if Reynolds_No <= 2300
                friction_factor = 64 / Reynolds_No;
                app.ViscosityDropDown.Value = "Laminar";
            elseif Reynolds_No >= 4000
                %f = 0.0055 ( 1 + (2 * 104 * k/D + 106/Re)**1/3)
                friction_factor = 0.0055 * (1 + power((2 * 104 * roughness + 106 / Reynolds_No),(1/3)));
                app.ViscosityDropDown.Value = "Turbulent";
            else
                app.ViscosityDropDown.Value = "Critical";
            end
            app.FrictionFactorEditField.Value = friction_factor;
            
            %Friction Loss(Darcy-Weisbach equation) hf = friction factor (Length/Diameter) x (velocity^2/2gravity) 
            frictionLoss = friction_factor * (app.PipeLengthEditField.Value/pipeDiameter) * (pow2(app.FluidVelocityEditField.Value) / (9.8*2));
            
            %Pipe Fitting Loss (h = manufacturer K factor x velocity² / 2gravity) 
            pipeFittingLoss = (app.ManufacturerLossCoefficientKFactorEditField.Value * pow2(fluidVel)/(9.8*2));
            
            %Pressure Drop
            pressureDrop = app.TankPressureEditField.Value - frictionLoss - pipeFittingLoss;
            app.PressureDropEditField.Value = pressureDrop;
            
            %Pressure Difference 
            pressureDifference = pressureDrop - app.TankPressureEditField.Value;
            app.PressureDifferenceTankInletEditField.Value = pressureDifference;
            
            %Pipe Loss due to elevation change
            
            %OUTPUT 
            %Reynolds_no;
            app.ReynoldsNoEditField.Value = Reynolds_No;
            %Kinematic viscosity
            app.KinematicViscosityEditField.Value = kinematic_visc;
            %friction_loss
            app.FrictionLossEditField.Value = frictionLoss;
            %Pipe Fitting Loss
            app.FittingsLossEditField.Value = pipeFittingLoss;
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.PipeAreaEditField.Value = 0;
            app.PipeLengthEditField.Value = 0;
            app.PipeAreaEditField.Value = 0;
            app.ManufacturerLossCoefficientKFactorEditField.Value = 0;
            app.FluidVelocityEditField.Value = 0;
            app.FluidDensityEditField.Value = 0;
            app.TankPressureEditField.Value = 0;
            app.ChangeinPipeHeightEditField.Value = 0;
            app.FrictionFactorEditField.Value = 0;
            app.RoughnessEditField.Value = 0;
            app.ReynoldsNoEditField.Value = 0;
            app.FrictionLossEditField.Value = 0;
            app.FittingsLossEditField.Value = 0;
            app.KinematicViscosityEditField.Value = 0;
            app.PressurelossduetoelevationEditField.Value = 0;
            app.PressureDropEditField.Value = 0;
            app.PressureDropElevationEditField.Value = 0;
            app.PressureDifferenceTankInletEditField.Value = 0;
        end

        % Menu selected function: File
        function FileMenuSelected(app, event)
            
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            app.PipeAreaEditField.Value = 0;
            app.PipeLengthEditField.Value = 0;
            app.PipeAreaEditField.Value = 0;
            app.ManufacturerLossCoefficientKFactorEditField.Value = 0;
            app.FluidVelocityEditField.Value = 0;
            app.FluidDensityEditField.Value = 0;
            app.TankPressureEditField.Value = 0;
            app.ChangeinPipeHeightEditField.Value = 0;
            app.FrictionFactorEditField.Value = 0;
            app.RoughnessEditField.Value = 0;
            app.ReynoldsNoEditField.Value = 0;
            app.FrictionLossEditField.Value = 0;
            app.FittingsLossEditField.Value = 0;
            app.KinematicViscosityEditField.Value = 0;
            app.PressurelossduetoelevationEditField.Value = 0;
            app.PressureDropEditField.Value = 0;
            app.PressureDropElevationEditField.Value = 0;
            app.PressureDifferenceTankInletEditField.Value = 0
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            fig = uifigure;
            uialert(fig,'Version 1.0','About');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.File.Text = 'File';
            app.AboutMenu = uimenu(app.File);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ContinuousPipeMenu = uimenu(app.UIFigure);
            app.ContinuousPipeMenu.Text = 'Continuous Pipe';
            app.AddSectionMenu = uimenu(app.ContinuousPipeMenu);
            app.AddSectionMenu.Text = 'Add Section';
            app.RemoveSectionMenu = uimenu(app.ContinuousPipeMenu);
            app.RemoveSectionMenu.Text = 'Remove Section';
            app.ModifySectionMenu = uimenu(app.ContinuousPipeMenu);
            app.ModifySectionMenu.Text = 'Modify Section';
            app.ResetMenu = uimenu(app.UIFigure);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HarbardFluidsLabel = uilabel(app.UIFigure);
            app.HarbardFluidsLabel.Text = 'Harbard - Fluids';
            app.PipeLengthEditFieldLabel = uilabel(app.UIFigure);
            app.PipeLengthEditFieldLabel.Text = 'Pipe Length';
            app.PipeLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PipeLengthEditField.ValueChangedFcn = createCallbackFcn(app, @PipeLengthEditFieldValueChanged, true);
            app.ChangeinPipeHeightEditFieldLabel = uilabel(app.UIFigure);
            app.ChangeinPipeHeightEditFieldLabel.Text = 'Change in Pipe Height';
            app.ChangeinPipeHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChangeinPipeHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeinPipeHeightEditFieldValueChanged, true);
            app.PipeAreaEditFieldLabel = uilabel(app.UIFigure);
            app.PipeAreaEditFieldLabel.Text = 'Pipe Area';
            app.PipeAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PipeAreaEditField.ValueChangedFcn = createCallbackFcn(app, @PipeAreaEditFieldValueChanged, true);
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.PipeDataLabel = uilabel(app.UIFigure);
            app.PipeDataLabel.Text = 'Pipe Data';
            app.FluidsDataLabel = uilabel(app.UIFigure);
            app.FluidsDataLabel.Text = 'Fluids Data';
            app.FluidVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.FluidVelocityEditFieldLabel.Text = 'Fluid Velocity';
            app.FluidVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FluidVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidVelocityEditFieldValueChanged, true);
            app.ViscosityDropDownLabel = uilabel(app.UIFigure);
            app.ViscosityDropDownLabel.Text = 'Viscosity';
            app.ViscosityDropDown = uidropdown(app.UIFigure);
            app.ViscosityDropDown.Items = {'Laminar', 'Turbulent'};
            app.ViscosityDropDown.ValueChangedFcn = createCallbackFcn(app, @ViscosityDropDownValueChanged, true);
            app.ViscosityDropDown.Value = 'Turbulent';
            app.PipeMaterialDropDownLabel = uilabel(app.UIFigure);
            app.PipeMaterialDropDownLabel.Text = 'Pipe Material';
            app.PipeMaterialDropDown = uidropdown(app.UIFigure);
            app.PipeMaterialDropDown.Items = {'Aluminium', 'Stainless Steel', ''};
            app.PipeMaterialDropDown.Value = 'Aluminium';
            app.FeedbackButton = uibutton(app.UIFigure, 'push');
            app.FeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButtonPushed, true);
            app.FeedbackButton.Text = 'Feedback';
            app.FrictionLossEditFieldLabel = uilabel(app.UIFigure);
            app.FrictionLossEditFieldLabel.Text = 'Friction Loss';
            app.FrictionLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureDropElevationEditFieldLabel = uilabel(app.UIFigure);
            app.PressureDropElevationEditFieldLabel.Text = 'Pressure Drop + Elevation';
            app.PressureDropElevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrictionFactorEditFieldLabel = uilabel(app.UIFigure);
            app.FrictionFactorEditFieldLabel.Text = 'Friction Factor';
            app.FrictionFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoughnessEditFieldLabel = uilabel(app.UIFigure);
            app.RoughnessEditFieldLabel.Text = 'Roughness';
            app.RoughnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReynoldsNoEditFieldLabel = uilabel(app.UIFigure);
            app.ReynoldsNoEditFieldLabel.Text = 'Reynolds No';
            app.ReynoldsNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureDropEditFieldLabel = uilabel(app.UIFigure);
            app.PressureDropEditFieldLabel.Text = 'Pressure Drop';
            app.PressureDropEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressurelossduetoelevationEditFieldLabel = uilabel(app.UIFigure);
            app.PressurelossduetoelevationEditFieldLabel.Text = 'Pressure loss due to elevation';
            app.PressurelossduetoelevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FluidDensityEditFieldLabel = uilabel(app.UIFigure);
            app.FluidDensityEditFieldLabel.Text = 'Fluid Density';
            app.FluidDensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.KinematicViscosityEditFieldLabel = uilabel(app.UIFigure);
            app.KinematicViscosityEditFieldLabel.Text = 'Kinematic Viscosity';
            app.KinematicViscosityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FittingsLossEditFieldLabel = uilabel(app.UIFigure);
            app.FittingsLossEditFieldLabel.Text = 'Fittings Loss';
            app.FittingsLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.PipeFittingsLossValvesSensorsLabel = uilabel(app.UIFigure);
            app.PipeFittingsLossValvesSensorsLabel.Text = 'Pipe Fittings Loss - Valves, Sensors';
            app.PressureDifferenceTankInletEditFieldLabel = uilabel(app.UIFigure);
            app.PressureDifferenceTankInletEditFieldLabel.Text = 'Pressure Difference (Tank-Inlet)';
            app.PressureDifferenceTankInletEditField = uieditfield(app.UIFigure, 'numeric');
            app.TankPressureEditFieldLabel = uilabel(app.UIFigure);
            app.TankPressureEditFieldLabel.Text = 'Tank Pressure';
            app.TankPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ManufacturerLossCoefficientKFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ManufacturerLossCoefficientKFactorEditFieldLabel.Text = 'Manufacturer Loss Coefficient (K Factor)';
            app.ManufacturerLossCoefficientKFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Version10Label = uilabel(app.UIFigure);
            app.Version10Label.Text = 'Version 1.0';
            app.DynamicViscosityCpEditFieldLabel = uilabel(app.UIFigure);
            app.DynamicViscosityCpEditFieldLabel.Text = 'Dynamic Viscosity (Cp)';
            app.DynamicViscosityCpEditField = uieditfield(app.UIFigure, 'numeric');
            app.metersLabel = uilabel(app.UIFigure);
            app.metersLabel.Text = 'meters';
            app.metersLabel_2 = uilabel(app.UIFigure);
            app.metersLabel_2.Text = 'meters';
            app.m2Label = uilabel(app.UIFigure);
            app.m2Label.Text = 'm^2';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.kgm3Label = uilabel(app.UIFigure);
            app.kgm3Label.Text = 'kg/m^3';
            app.psiLabel = uilabel(app.UIFigure);
            app.psiLabel.Text = 'psi';
            app.ContinuousPipeButton = uibutton(app.UIFigure, 'push');
            app.ContinuousPipeButton.Text = 'Continuous Pipe';
            app.PipeSectionsEditFieldLabel = uilabel(app.UIFigure);
            app.PipeSectionsEditFieldLabel.Text = 'Pipe Sections';
            app.PipeSectionsEditField = uieditfield(app.UIFigure, 'text');
            app.TotalPressureDifferenceEditFieldLabel = uilabel(app.UIFigure);
            app.TotalPressureDifferenceEditFieldLabel.Text = 'Total Pressure Difference';
            app.TotalPressureDifferenceEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Harbard_fluids

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end